classdef malatag_doe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChronoWaveUIFigure      matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        MALATAGCPE122L4C1Label  matlab.ui.control.Label
        GridLayout3             matlab.ui.container.GridLayout
        TIMESIGNALLabel         matlab.ui.control.Label
        RESULT                  matlab.ui.control.EditField
        EVALUATIONLabel         matlab.ui.control.Label
        INPUT                   matlab.ui.control.EditField
        INPUTEditFieldLabel     matlab.ui.control.Label
        GridLayout2             matlab.ui.container.GridLayout
        STABILITYButton         matlab.ui.control.Button
        INVARIANCEButton        matlab.ui.control.Button
        LINEARITYButton         matlab.ui.control.Button
        CAUSALITYButton         matlab.ui.control.Button
        ENERGYPOWERButton       matlab.ui.control.Button
        STATICDYNAMICButton     matlab.ui.control.Button
        TESTERSLabel            matlab.ui.control.Label
        Image                   matlab.ui.control.Image
    end

    methods (Access = public)

        function signal = getInputSignal(app)
            try

                if isnumeric(app.INPUT.Value)
                    inputSignal = string(app.INPUT.Value);
                else
                    inputSignal = strtrim(strjoin(string(app.INPUT.Value), ' '));
                end
                
                if isempty(inputSignal)
                    app.RESULT.Value = 'Error: Empty input';
                    signal = [];
                    return;
                end
        
                inputPattern = '(y|x)\(n\)\s*=\s*(.*)'; 
                tokens = regexp(inputSignal, inputPattern, 'tokens');
        
                if ~isempty(tokens)
                    inputSignal = strtrim(tokens{1}{2}); 
                else
                    app.RESULT.Value = 'Error: Invalid input format. Use "y(n) =" or "x(n) ="';
                    signal = [];
                    return;
                end
        
                syms x(n) n
        
                inputSignal = strrep(inputSignal, '^', '.^'); 
                inputSignal = strrep(inputSignal, '*', '.*'); 
                inputSignal = strrep(inputSignal, '/', './'); 
                signal = str2sym(inputSignal);
        
            catch ME
                app.RESULT.Value = ['Error: ', ME.message];
                signal = [];
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STATICDYNAMICButton
        function STATICDYNAMICButtonPushed(app, event)
            signal = app.getInputSignal();

            app.STATICDYNAMICButton.BackgroundColor = "#1a1a1a"
            app.ENERGYPOWERButton.BackgroundColor = "#cfbeab"
            app.CAUSALITYButton.BackgroundColor = "#cfbeab"
            app.LINEARITYButton.BackgroundColor = "#cfbeab"
            app.INVARIANCEButton.BackgroundColor = "#cfbeab"
            app.STABILITYButton.BackgroundColor = "#cfbeab"
            app.STATICDYNAMICButton.FontColor = "#c39d3a"
            app.ENERGYPOWERButton.FontColor = "#2e2e2d"
            app.CAUSALITYButton.FontColor = "#2e2e2d"
            app.LINEARITYButton.FontColor = "#2e2e2d"
            app.INVARIANCEButton.FontColor = "#2e2e2d"
            app.STABILITYButton.FontColor = "#2e2e2d"
            
            expr = char(signal);
            tokens = regexp(expr, 'x\((.*?)\)', 'tokens');
            
            if isempty(tokens)
                app.RESULT.Value = 'Static';
                return;
            end
            
            x_arg = strtrim(tokens{1}{1}); 
            if strcmp(x_arg, 'n')
                app.RESULT.Value = 'Static';
            else
                app.RESULT.Value = 'Dynamic'; 
            end
        end

        % Button pushed function: ENERGYPOWERButton
        function ENERGYPOWERButtonPushed(app, event)
            signal = app.getInputSignal();

            app.STATICDYNAMICButton.BackgroundColor = "#cfbeab"
            app.ENERGYPOWERButton.BackgroundColor = "#1a1a1a"
            app.CAUSALITYButton.BackgroundColor = "#cfbeab"
            app.LINEARITYButton.BackgroundColor = "#cfbeab"
            app.INVARIANCEButton.BackgroundColor = "#cfbeab"
            app.STABILITYButton.BackgroundColor = "#cfbeab"
            app.STATICDYNAMICButton.FontColor = "#2e2e2d"
            app.ENERGYPOWERButton.FontColor = "#c39d3a"
            app.CAUSALITYButton.FontColor = "#2e2e2d"
            app.LINEARITYButton.FontColor = "#2e2e2d"
            app.INVARIANCEButton.FontColor = "#2e2e2d"
            app.STABILITYButton.FontColor = "#2e2e2d"
        
            syms n integer;
            syms x(n);
            
            try
                delta(n) = kroneckerDelta(n, 0);
                
                h(n) = subs(signal, {x(n), x(-n)}, {delta(n), delta(-n)});
                
                N = 1000;
                energy = 0;
                for k = -N:N
                    h_k = double(subs(h(n), n, k));
                    energy = energy + abs(h_k)^2;
                end
                
                if isfinite(energy) && energy < Inf
                    app.RESULT.Value = 'Energy Signal';
                 
                else
                    N_power = 10000;
                    power = sum(abs(double(subs(h(n), n, -N_power:N_power))).^2 / (2*N_power + 1));
                    if isfinite(power)
                        app.RESULT.Value = 'Power Signal';
                    else
                        app.RESULT.Value = 'Neither';
                    end
                end
                
            catch ME
                app.RESULT.Value = ['Error: ', ME.message];
            end
        end

        % Button pushed function: CAUSALITYButton
        function CAUSALITYButtonPushed(app, event)
            signal = app.getInputSignal();

            app.STATICDYNAMICButton.BackgroundColor = "#cfbeab"
            app.ENERGYPOWERButton.BackgroundColor = "#cfbeab"
            app.CAUSALITYButton.BackgroundColor = "#1a1a1a"
            app.LINEARITYButton.BackgroundColor = "#cfbeab"
            app.INVARIANCEButton.BackgroundColor = "#cfbeab"
            app.STABILITYButton.BackgroundColor = "#cfbeab"
            app.STATICDYNAMICButton.FontColor = "#2e2e2d"
            app.ENERGYPOWERButton.FontColor = "#2e2e2d"
            app.CAUSALITYButton.FontColor = "#c39d3a"
            app.LINEARITYButton.FontColor = "#2e2e2d"
            app.INVARIANCEButton.FontColor = "#2e2e2d"
            app.STABILITYButton.FontColor = "#2e2e2d"

            expr = char(signal);
            
            xIndices = regexp(expr, 'x\s*\(\s*([^)]*)\s*\)', 'tokens');
            
            isNonCausal = false;
            
            causalPattern = '^\s*n\s*(-\s*\d+\s*)?$';
            
            for i = 1:length(xIndices)
                indexStr = strtrim(xIndices{i}{1});
                if isempty(regexp(indexStr, causalPattern, 'once'))
                    isNonCausal = true;
                    break;
                end
            end
            
            if isNonCausal
                app.RESULT.Value = 'Non-Causal';
            else
                app.RESULT.Value = 'Causal';
            end
        end

        % Button pushed function: LINEARITYButton
        function LINEARITYButtonPushed(app, event)
            signal = app.getInputSignal();

            app.STATICDYNAMICButton.BackgroundColor = "#cfbeab"
            app.ENERGYPOWERButton.BackgroundColor = "#cfbeab"
            app.CAUSALITYButton.BackgroundColor = "#cfbeab"
            app.LINEARITYButton.BackgroundColor = "#1a1a1a"
            app.INVARIANCEButton.BackgroundColor = "#cfbeab"
            app.STABILITYButton.BackgroundColor = "#cfbeab"
            app.STATICDYNAMICButton.FontColor = "#2e2e2d"
            app.ENERGYPOWERButton.FontColor = "#2e2e2d"
            app.CAUSALITYButton.FontColor = "#2e2e2d"
            app.LINEARITYButton.FontColor = "#c39d3a"
            app.INVARIANCEButton.FontColor = "#2e2e2d"
            app.STABILITYButton.FontColor = "#2e2e2d"
            
            syms n integer;
            syms x(n) x1(n) x2(n) a b;
            
            try
                T_ax1_bx2 = subs(signal, x, a*x1 + b*x2);
                T_x1 = subs(signal, x, x1);
                T_x2 = subs(signal, x, x2);
                aT_plus_bT = a*T_x1 + b*T_x2;
                
               
                isLinear = logical(isAlways(T_ax1_bx2 == aT_plus_bT));
                
               
                if isLinear
                    app.RESULT.Value = 'Linear';
                else
                    app.RESULT.Value = 'Non-Linear';
                end
                
            catch ME
                app.RESULT.Value = ['Error: ', ME.message];
            end
        end

        % Button pushed function: INVARIANCEButton
        function INVARIANCEButtonPushed(app, event)
            signal = app.getInputSignal();

            app.STATICDYNAMICButton.BackgroundColor = "#cfbeab"
            app.ENERGYPOWERButton.BackgroundColor = "#cfbeab"
            app.CAUSALITYButton.BackgroundColor = "#cfbeab"
            app.LINEARITYButton.BackgroundColor = "#cfbeab"
            app.INVARIANCEButton.BackgroundColor = "#1a1a1a"
            app.STABILITYButton.BackgroundColor = "#cfbeab"
            app.STATICDYNAMICButton.FontColor = "#2e2e2d"
            app.ENERGYPOWERButton.FontColor = "#2e2e2d"
            app.CAUSALITYButton.FontColor = "#2e2e2d"
            app.LINEARITYButton.FontColor = "#2e2e2d"
            app.INVARIANCEButton.FontColor = "#c39d3a"
            app.STABILITYButton.FontColor = "#2e2e2d"
            
            syms n k integer;
            syms x(n);
            
            try

                x_shift(n) = x(n - k);
                T_x_shift = subs(signal, x(n), x_shift); 
                y_shifted = subs(signal, n, n - k);     
                
                isInvariant = logical(isAlways(T_x_shift == y_shifted, 'Unknown', 'false'));
                
                if isInvariant
                    app.RESULT.Value = 'Time Invariant';
                else
                    app.RESULT.Value = 'Time Variant';
                end
                
            catch ME
                app.RESULT.Value = ['Error: ', ME.message];
            end
        end

        % Button pushed function: STABILITYButton
        function STABILITYButtonPushed(app, event)
            signal = app.getInputSignal();

            app.STATICDYNAMICButton.BackgroundColor = "#cfbeab"
            app.ENERGYPOWERButton.BackgroundColor = "#cfbeab"
            app.CAUSALITYButton.BackgroundColor = "#cfbeab"
            app.LINEARITYButton.BackgroundColor = "#cfbeab"
            app.INVARIANCEButton.BackgroundColor = "#cfbeab"
            app.STABILITYButton.BackgroundColor = "#1a1a1a"
            app.STATICDYNAMICButton.FontColor = "#2e2e2d"
            app.ENERGYPOWERButton.FontColor = "#2e2e2d"
            app.CAUSALITYButton.FontColor = "#2e2e2d"
            app.LINEARITYButton.FontColor = "#2e2e2d"
            app.INVARIANCEButton.FontColor = "#2e2e2d"
            app.STABILITYButton.FontColor = "#c39d3a"
        
            expr = char(signal);
        
            unstableTerms = regexp(expr, 'n\s*[\*\.\*]\s*x\(.*?\)|n\^|\d+\^n|exp\(n\)|factorial\(n\)|\(?\s*x\s*\(\s*n\s*\)\s*\)?\s*\^\s*n', 'match');
        
            if ~isempty(unstableTerms)
                app.RESULT.Value = 'Unstable';
            else
                try
                    N = 1000;
                    x_test = ones(1, N);
                    n_values = 0:N-1;
                    y = double(subs(signal, {n, x(n)}, {n_values, x_test}));
                    
                    if ~all(isfinite(y))
                        app.RESULT.Value = 'Unstable';
                    else
                        threshold = 100; 
                        if max(abs(y)) > threshold
                            app.RESULT.Value = 'Unstable';
                        else
                            app.RESULT.Value = 'Stable';
                        end
                    end
                catch
                    app.RESULT.Value = 'Stable (Assumed)';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ChronoWaveUIFigure = uifigure('Visible', 'off');
            app.ChronoWaveUIFigure.Name = 'ChronoWave';
            app.GridLayout = uigridlayout(app.ChronoWaveUIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Assets', 'title.png');
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.TESTERSLabel = uilabel(app.GridLayout2);
            app.TESTERSLabel.Text = 'T E S T E R S';
            app.STATICDYNAMICButton = uibutton(app.GridLayout2, 'push');
            app.STATICDYNAMICButton.ButtonPushedFcn = createCallbackFcn(app, @STATICDYNAMICButtonPushed, true);
            app.STATICDYNAMICButton.Text = 'STATIC/DYNAMIC';
            app.ENERGYPOWERButton = uibutton(app.GridLayout2, 'push');
            app.ENERGYPOWERButton.ButtonPushedFcn = createCallbackFcn(app, @ENERGYPOWERButtonPushed, true);
            app.ENERGYPOWERButton.Text = 'ENERGY/POWER';
            app.CAUSALITYButton = uibutton(app.GridLayout2, 'push');
            app.CAUSALITYButton.ButtonPushedFcn = createCallbackFcn(app, @CAUSALITYButtonPushed, true);
            app.CAUSALITYButton.Text = 'CAUSALITY';
            app.LINEARITYButton = uibutton(app.GridLayout2, 'push');
            app.LINEARITYButton.ButtonPushedFcn = createCallbackFcn(app, @LINEARITYButtonPushed, true);
            app.LINEARITYButton.Text = 'LINEARITY';
            app.INVARIANCEButton = uibutton(app.GridLayout2, 'push');
            app.INVARIANCEButton.ButtonPushedFcn = createCallbackFcn(app, @INVARIANCEButtonPushed, true);
            app.INVARIANCEButton.Text = 'INVARIANCE';
            app.STABILITYButton = uibutton(app.GridLayout2, 'push');
            app.STABILITYButton.ButtonPushedFcn = createCallbackFcn(app, @STABILITYButtonPushed, true);
            app.STABILITYButton.Text = 'STABILITY';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.INPUTEditFieldLabel = uilabel(app.GridLayout3);
            app.INPUTEditFieldLabel.Text = 'INPUT';
            app.INPUT = uieditfield(app.GridLayout3, 'text');
            app.EVALUATIONLabel = uilabel(app.GridLayout3);
            app.EVALUATIONLabel.Text = 'EVALUATION';
            app.RESULT = uieditfield(app.GridLayout3, 'text');
            app.TIMESIGNALLabel = uilabel(app.GridLayout3);
            app.TIMESIGNALLabel.Text = 'T I M E  S I G N A L';
            app.MALATAGCPE122L4C1Label = uilabel(app.GridLayout);
            app.MALATAGCPE122L4C1Label.Text = {'>         * MALATAG'; 'CPE122L-4 C1'};
            % Show the figure after all components are created
            app.ChronoWaveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = malatag_doe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChronoWaveUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChronoWaveUIFigure)
        end
    end
end